classdef Sweep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        MaxRangeEditField           matlab.ui.control.NumericEditField
        MaxRangeEditFieldLabel      matlab.ui.control.Label
        ComplianceEditField         matlab.ui.control.NumericEditField
        ComplianceEditFieldLabel    matlab.ui.control.Label
        FinalVoltageEditField       matlab.ui.control.NumericEditField
        FinalVoltageEditFieldLabel  matlab.ui.control.Label
        ScanButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        ConnectedtoLabel            matlab.ui.control.Label
        StepCountEditField          matlab.ui.control.NumericEditField
        StepCountEditFieldLabel     matlab.ui.control.Label
        StartVoltageEditField       matlab.ui.control.NumericEditField
        StartVoltageEditFieldLabel  matlab.ui.control.Label
        ModeDropDown                matlab.ui.control.DropDown
        ModeDropDownLabel           matlab.ui.control.Label
        DeviceDropDown              matlab.ui.control.DropDown
        DeviceDropDownLabel         matlab.ui.control.Label
        ConnectButton               matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        RecordingLamp               matlab.ui.control.Lamp
        RecordingLampLabel          matlab.ui.control.Label
        ALabel                      matlab.ui.control.Label
        MeasuredCurrentLabel        matlab.ui.control.Label
        VLabel                      matlab.ui.control.Label
        MeasuredVoltageLabel        matlab.ui.control.Label
        OutputLabel                 matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        visaDevices % stores the connected visa devices
        deviceSelected % stores the selected device
        sourceMeter % visa Object of the selected device
        volsweep = true; % voltage or current sweep
    end
    
    methods (Access = private)
        
        function voltageSweep(app)
            try
        
            % Define the voltage sweep parameters
            startVoltage = app.StartVoltageEditField.Value; % Starting voltage in volts
            endVoltage = app.FinalVoltageEditField.Value;   % Ending voltage in volts
            stepVoltage = app.StepCountEditField.Value; % Step size in volts
        
            % Generate the voltage sweep values
            voltageValues = startVoltage:stepVoltage:endVoltage;
        
            % Initialize an array to store the measured current
            measuredCurrent = zeros(size(voltageValues));

            app.sourceMeter.sendCommand('*RST');

            app.sourceMeter.sendCommand(':SENS:FUNC CURR');


            % Set Measure Range
                app.sourceMeter.setCurrentLimit(app.MaxRangeEditField.Value);

            % Set Compliance current
               % app.sourceMeter.setCurrentCompLimit(app.ComplianceEditField.Value);

                app.sourceMeter.sendCommand(':SOUR:FUNC VOLT');

                % Turn Output on
                app.sourceMeter.enableOutput(true);
        
            % Perform the voltage sweep
            disp('Starting voltage sweep...');
            for i = 1:length(voltageValues)
                % Set the voltage using SCPI command
                app.sourceMeter.setVoltageSource(num2str(voltageValues(i)));

        
                % Measure the current using SCPI command
                current = app.sourceMeter.measureCurrent();
        
                % Store the measured current
                measuredCurrent(i) = str2double(current);
        
                % Display the result
                disp(['Voltage: ', num2str(voltageValues(i)), ' V, Current: ', current, ' A']);
                app.MeasuredCurrentLabel.Text = num2str(measuredCurrent(i));
                app.MeasuredVoltageLabel.Text = num2str(voltageValues(i));
            end
        
            % Plot the voltage vs. current
            plot(app.UIAxes, voltageValues, measuredCurrent, '-o');
            app.UIAxes.XLabel('Voltage (V)');
            app.UIAxes.YLabel('Current (A)');
            app.UIAxes.title('Voltage Sweep');
            grid on;
        
            catch ME
            % Handle errors and clean up
            disp('An error occurred:');
            disp(ME.message);   
            end
        end

        function currentSweep(app)
            try
        
            % Define the current sweep parameters
            startCurrent = app.StartVoltageEditField.Value; % Starting current in amps
            endCurrent = app.FinalVoltageEditField.Value;   % Ending current in amps
            stepCurrent = app.StepCountEditField.Value; % Step size in amps
        
            % Generate the current sweep values
            currentValues = startCurrent:stepCurrent:endCurrent;
        
            % Initialize an array to store the measured voltage
            measuredVoltage = zeros(size(currentValues));

            app.sourceMeter.sendCommand('*RST');

            app.sourceMeter.sendCommand(':SENS:FUNC "CURR"');

            % Set Measure range
            app.sourceMeter.setVoltageLimit(app.MaxRangeEditField.Value);

            % Set Compliance Voltage
            app.sourceMeter.setVoltageCompLimit(app.ComplianceEditField.Value);
        
            % Perform the current sweep
            disp('Starting voltage sweep...');
            for i = 1:length(currentValues)
                % Set the current using SCPI command
                app.sourceMeter.setCurrentSource(num2str(currentValues(i)));

                % Turn Output on
                app.sourceMeter.enableOutput(true);
        
                % Measure the voltage using SCPI command
                voltage = app.sourceMeter.measureVoltage();
        
                % Store the measured voltage
                measuredVoltage(i) = str2double(voltage);
        
                % Display the result
                disp(['Current: ', num2str(currentValues(i)), ' V, Voltage: ', voltage, ' A']);
                app.MeasuredCurrentLabel.Text = num2str(currentValues(i));
                app.MeasuredVoltageLabel.Text = num2str(measuredVoltage(i));
            end
        
            % Plot the current vs. voltage
            plot(app.UIAxes, currentValues, measuredVoltage, '-o');
            app.UIAxes.XLabel('Current (A)');
            app.UIAxes.YLabel('Voltage (V)');
            app.UIAxes.title('Current Sweep');
            grid on;
        
            catch ME
            % Handle errors and clean up
            disp('An error occurred:');
            disp(ME.message);   
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            try
                app.visaDevices = visadevlist;
                if ~isempty(app.visaDevices)
                     app.DeviceDropDown.Items = app.visaDevices.Model;
                     app.DeviceDropDown.ItemsData = app.visaDevices.ResourceName;
                end
            catch Exc
                app.ConnectedtoLabel.Text = "No Devices Found" + Exc.message;
                disp(Exc.message);
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.deviceSelected = app.DeviceDropDown.Value;
            try
                app.sourceMeter = Keithley_2450(app.deviceSelected);
                app.ConnectedtoLabel.Text = "Device Connected successfully";
            catch ME
                app.ConnectedtoLabel.Text = ME.message;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~isempty(app.sourceMeter)
                app.RecordingLamp.Color = [0.0,1.0,0.0];
                if strcmp(app.ModeDropDown.Value, "Voltage Sweep")
                    app.voltageSweep;
                else
                    app.currentSweep;
                end
                app.sourceMeter.enableOutput(false);
                app.RecordingLamp.Color = [1.0,0.0,0.0];
            else
                app.ConnectedtoLabel.Text = "NO Devices connected";
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            if strcmp(app.ModeDropDown.Value, "Voltage Sweep")
                app.StartVoltageEditFieldLabel.Text = "Start Voltage";
                app.FinalVoltageEditFieldLabel.Text = "Final Voltage";
            else
                app.StartVoltageEditFieldLabel.Text = "Start Current";
                app.FinalVoltageEditFieldLabel.Text = "Final Current";
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DeviceDropDownLabel = uilabel(app.LeftPanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.LeftPanel);
            app.DeviceDropDown.Items = {'None'};
            app.DeviceDropDown.Value = 'None';
            app.ModeDropDownLabel = uilabel(app.LeftPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.LeftPanel);
            app.ModeDropDown.Items = {'Voltage Sweep', 'Current Sweep'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Voltage Sweep';
            app.StartVoltageEditFieldLabel = uilabel(app.LeftPanel);
            app.StartVoltageEditFieldLabel.Text = 'Start Voltage';
            app.StartVoltageEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StepCountEditFieldLabel = uilabel(app.LeftPanel);
            app.StepCountEditFieldLabel.Text = 'Step Count';
            app.StepCountEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ConnectedtoLabel = uilabel(app.LeftPanel);
            app.ConnectedtoLabel.Text = 'Connected to :';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ScanButton = uibutton(app.LeftPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.FinalVoltageEditFieldLabel = uilabel(app.LeftPanel);
            app.FinalVoltageEditFieldLabel.Text = 'Final Voltage';
            app.FinalVoltageEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ComplianceEditFieldLabel = uilabel(app.LeftPanel);
            app.ComplianceEditFieldLabel.Text = 'Compliance';
            app.ComplianceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxRangeEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxRangeEditFieldLabel.Text = 'Max Range';
            app.MaxRangeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'current')
            ylabel(app.UIAxes, 'voltage')
            zlabel(app.UIAxes, 'Z')
            app.OutputLabel = uilabel(app.RightPanel);
            app.OutputLabel.Text = 'Output';
            app.MeasuredVoltageLabel = uilabel(app.RightPanel);
            app.MeasuredVoltageLabel.Text = 'Measured Voltage : ';
            app.VLabel = uilabel(app.RightPanel);
            app.VLabel.Text = '0 V';
            app.MeasuredCurrentLabel = uilabel(app.RightPanel);
            app.MeasuredCurrentLabel.Text = 'Measured Current : ';
            app.ALabel = uilabel(app.RightPanel);
            app.ALabel.Text = '0 A';
            app.RecordingLampLabel = uilabel(app.RightPanel);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sweep

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end