classdef oscilloscope_C < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        DurationinsecSlider         matlab.ui.control.Slider
        DurationinsecSliderLabel    matlab.ui.control.Label
        connectionLabel             matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        FilePathEditField           matlab.ui.control.EditField
        FilePathEditFieldLabel      matlab.ui.control.Label
        ConnectButton               matlab.ui.control.Button
        ScanButton                  matlab.ui.control.Button
        devicedropdown              matlab.ui.control.DropDown
        OscilloscopeDropDownLabel   matlab.ui.control.Label
        StartRecordingButton        matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        OutputandlogsTextArea       matlab.ui.control.TextArea
        OutputandlogsTextAreaLabel  matlab.ui.control.Label
        RecordingLamp               matlab.ui.control.Lamp
        RecordingLampLabel          matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        visaDevices % Description
        oscilloscope % oscilloscope object
    end
    
    methods (Access = private)
        
        function record(app, duration)
            [channelData.time, channelData.voltage] = app.oscilloscope.recordWaveform('CH1', duration);

            plot(app.UIAxes, channelData.time, channelData.voltage);
            %app.UIAxes.XLabel 
            %app.UIAxes.YLabel = "Voltage";
            %app.UIAxes.Title = "Current Waveform from Tektronix DPO 2014";

            app.RecordingLamp.Color = [1.0, 0.0, 0.0];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            try
                app.visaDevices = visadevlist;
                 if ~isempty(app.visaDevices)
                     app.devicedropdown.Items = app.visaDevices.Model;
                     app.devicedropdown.ItemsData = app.visaDevices.ResourceName;
                 end
            catch Me
                app.OutputandlogsTextArea.Value = Me.message;
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if ~isempty(app.visaDevices)
                try
                    app.oscilloscope = Oscilloscope(app.devicedropdown.Value);
                    app.connectionLabel.Text = "Connected to: " + app.oscilloscope.query("*IDN?");
                catch ME
                    app.connectionLabel.Text = "";
                    app.OutputandlogsTextArea.Value = ME.message;
                end
            else
                app.OutputandlogsTextArea.Value = "No Device selected";
            end
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            if ~isempty(app.oscilloscope)
                app.RecordingLamp.Color = [0.0,1.0,0.0];
                app.record(app.DurationinsecSlider.Value);
            else
                app.OutputandlogsTextArea.Value = "No device connected";
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartRecordingButton = uibutton(app.LeftPanel, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.OscilloscopeDropDownLabel = uilabel(app.LeftPanel);
            app.OscilloscopeDropDownLabel.Text = 'Oscilloscope';
            app.devicedropdown = uidropdown(app.LeftPanel);
            app.devicedropdown.Items = {'None'};
            app.devicedropdown.Value = 'None';
            app.ScanButton = uibutton(app.LeftPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.FilePathEditFieldLabel = uilabel(app.LeftPanel);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.LeftPanel, 'text');
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.connectionLabel = uilabel(app.LeftPanel);
            app.connectionLabel.Text = '';
            app.DurationinsecSliderLabel = uilabel(app.LeftPanel);
            app.DurationinsecSliderLabel.Text = 'Duration (in sec)';
            app.DurationinsecSlider = uislider(app.LeftPanel);
            app.DurationinsecSlider.Limits = [1 5];
            app.DurationinsecSlider.MajorTicks = [1 2 3 4 5];
            app.DurationinsecSlider.MajorTickLabels = {'1', '2', '3', '4', '5'};
            app.DurationinsecSlider.MinorTicks = [0 0.5 1.5 2.5 3.5 4.5];
            app.DurationinsecSlider.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-Inf Inf];
            app.RecordingLampLabel = uilabel(app.RightPanel);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.RightPanel);
            app.OutputandlogsTextAreaLabel = uilabel(app.RightPanel);
            app.OutputandlogsTextAreaLabel.Text = 'Output and logs';
            app.OutputandlogsTextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oscilloscope_C

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end